classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RpEditField                    matlab.ui.control.NumericEditField
        RpEditFieldLabel               matlab.ui.control.Label
        AsEditField                    matlab.ui.control.NumericEditField
        AsEditFieldLabel               matlab.ui.control.Label
        TcoefficientEditField          matlab.ui.control.EditField
        TcoefficientLabel              matlab.ui.control.Label
        KaiserbetaEditField            matlab.ui.control.NumericEditField
        KaiserbetaEditFieldLabel       matlab.ui.control.Label
        windowcategory                 matlab.ui.control.DropDown
        FiltercategoryDropDown_3Label_4  matlab.ui.control.Label
        FIRdesigntype                  matlab.ui.control.DropDown
        FiltercategoryDropDown_3Label_3  matlab.ui.control.Label
        FIRlengthEditField             matlab.ui.control.NumericEditField
        FIRlengthEditFieldLabel        matlab.ui.control.Label
        IIRcategory                    matlab.ui.control.DropDown
        FiltercategoryDropDown_3Label_2  matlab.ui.control.Label
        ws                             matlab.ui.control.EditField
        Label_2                        matlab.ui.control.Label
        wp                             matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
        Filtercategory                 matlab.ui.control.DropDown
        FiltercategoryDropDown_3Label  matlab.ui.control.Label
        Filterlengthcategory           matlab.ui.control.DropDown
        FilterlengthcategoryDropDownLabel  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        angle                          matlab.ui.control.UIAxes
        amp                            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateButton
        function Update_cb(app, event)
            filterlengthtype = app.Filterlengthcategory.Value;
            switch filterlengthtype
                case 'IIR'
                    As = app.AsEditField.Value;
                    Rp = app.RpEditField.Value;
                    wp = str2num(app.wp.Value);
                    ws = str2num(app.ws.Value);
                    switch app.Filtercategory.Value
                        case 'lowpass'
                            [n,Wn] = buttord(wp, ws, Rp, As);
                            [b,a] = butter(n, Wn);
                            [H,w] = freqz(b,a);
                            plot(app.amp, w, abs(H), 'b');
                            plot(app.angle, w, angle(H), 'r');
                            title(app.amp, 'IIR lowpass Amp response');
                            title(app.angle, 'IIR lowpass angle response');
                            xlabel(app.amp, 'w/pi'); xlabel(app.angle, 'w/pi');
                            ylabel(app.amp, 'Amp'); ylabel(app.angle, 'Angle');
                        case 'highpass'
                            [n,Wn] = buttord(wp, ws, Rp, As);
                            [b,a] = butter(n, Wn, 'high');
                            [H,w] = freqz(b,a);
                            plot(app.amp, w, abs(H), 'b');
                            plot(app.angle, w, angle(H), 'r');
                            title(app.amp, 'IIR highpass Amp response');
                            title(app.amp, 'IIR highpass angle response');
                            xlabel(app.amp, 'w/pi'); xlabel(app.angle, 'w/pi');
                            ylabel(app.amp, 'Amp'); ylabel(app.angle, 'Angle');
                        case 'bandpass'
                            [n,Wn] = buttord(wp, ws, Rp, As);
                            [b,a] = butter(n, Wn, 'bandpass');
                            [H,w] = freqz(b,a);
                            plot(app.amp, w, abs(H), 'b');
                            plot(app.angle, w, angle(H), 'r');
                            title(app.amp, 'IIR bandpass Amp response');
                            title(app.amp, 'IIR bandpass angle response');
                            xlabel(app.amp, 'w/pi'); xlabel(app.angle, 'w/pi');
                            ylabel(app.amp, 'Amp'); ylabel(app.angle, 'Angle');
                        case 'bandstop'
                            [n,Wn] = buttord(wp, ws, Rp, As);
                            [b,a] = butter(n, Wn, 'stop');
                            [H,w] = freqz(b,a);
                            plot(app.amp, w, abs(H), 'b');
                            plot(app.angle, w, angle(H), 'r');
                            title(app.amp, 'IIR bandstop Amp response');
                            title(app.amp, 'IIR bandstop angle response');
                            xlabel(app.amp, 'w/pi'); xlabel(app.angle, 'w/pi');
                            ylabel(app.amp, 'Amp'); ylabel(app.angle, 'Angle');
                        otherwise
                            title(app.amp, 'IIR unknown Amp response');
                            title(app.angle, 'IIR unknow angle response');
                            xlabel(app.amp, 'w/pi'); xlabel(app.angle, 'w/pi');
                            ylabel(app.amp, 'Amp'); ylabel(app.angle, 'Angle');
                    end
                otherwise
                    Firdesigntype = app.FIRdesigntype.Value;
                    wp = str2num(app.wp.Value)*pi;
                    ws = str2num(app.ws.Value)*pi;
                    switch Firdesigntype
                        case 'frequency sample'
                            N = app.FIRlengthEditField.Value;
                            t_num = length(str2num(app.TcoefficientEditField.Value));
                            t_coff = str2num(app.TcoefficientEditField.Value);
                            switch app.Filtercategory.Value
                                case 'lowpass'
                                    wc = (wp + ws)/2;
                                    n_c = ceil(wc/(2*pi)*N);
                                    n = 0:1:n_c+t_num-1;
                                    if mod(N, 2) == 0
                                        Hk = [ones([1,n_c + t_num]), zeros([1,N/2+1-n_c-t_num])];
                                        Hk(1:n_c+t_num) = exp(-1i*pi*(N-1)/N.*n);
                                        for i = 1:t_num
                                            Hk(n_c + i) = Hk(n_c + i) * t_coff(i);
                                        end
                                        Hk = [Hk, flip(conj(Hk(2:length(Hk)-1)))];
                                    else
                                        Hk = [ones([1, n_c + t_num]), zeros([1,(N+1)/2-n_c-t_num])];
                                        Hk(1:n_c+t_num) = exp(-1i*pi*(N-1)/N.*n);
                                        for i = 1:t_num
                                            Hk(n_c + i) = Hk(n_c + i) * t_coff(i);
                                        end
                                        Hk = [Hk, flip(conj(Hk(2:length(Hk))))];
                                    end
                                case 'highpass'
                                    wc = (wp+ws)/2;
                                    n_c = ceil(wc/(2*pi)*N);
                                    if mod(N, 2) == 0
                                        Hk = [zeros([1,N/2+1-n_c-t_num]), ones([1,n_c + t_num])];
                                        n = N/2-n_c-t_num+1:1:N/2+1;
                                        Hk(N/2+1-n_c-t_num+1:N/2+1) = exp(-1i*pi*(N-1)/N.*n);
                                        for i = 1:t_num
                                            Hk(N/2+2-n_c-i) = Hk(N/2+2-n_c-i) * t_coff(t_num+1-i);
                                        end
                                        Hk = [Hk, flip(conj(Hk(2:N/2)))];
                                    else
                                        Hk = [zeros([1,(N+1)/2-n_c-t_num]), ones([1, n_c + t_num])];
                                        n = ceil(N/2)-n_c-t_num+1:1:ceil(N/2);
                                        Hk(ceil(N/2)-n_c-t_num+1:ceil(N/2)) = exp(-1i*pi*(N-1)/N.*n);
                                        for i = 1:t_num
                                            Hk(ceil(N/2)+1-n_c-i) = Hk(ceil(N/2)+1-n_c-i) * t_coff(t_num+1-i);
                                        end
                                        Hk = [Hk, flip(conj(Hk(2:ceil(N/2))))];
                                    end
                                case 'bandpass'
                                    wc1 = (wp(1)+ws(1))/2;
                                    wc2 = (wp(2)+ws(2))/2;
                                    n_c1 = ceil(wc1/(2*pi)*N);
                                    n_c2 = floor(wc2/(2*pi)*N);
                                    if mod(N, 2) == 0
                                        Hk = [zeros([1,n_c1]),ones([1,n_c2-n_c1+1]),zeros([1,N/2+1-n_c2-1])];
                                        n = n_c1:1:n_c2;
                                        Hk(n_c1:n_c2) = exp(-1i*pi*(N-1)/N.*n);
                                        for i = 1:t_num
                                            Hk(n_c2+i) = Hk(n_c2+i) * t_coff(i);
                                            Hk(n_c1-i) = Hk(n_c1-i) * t_coff(i);
                                        end
                                        Hk = [Hk, flip(conj(Hk(2:N/2)))];
                                    else
                                        Hk = [zeros([1,n_c1]),ones([1,n_c2-n_c1+1]),zeros([1,ceil(N/2)-n_c2-1])];
                                        n = n_c1:1:n_c2;
                                        Hk(n_c1:n_c2) = exp(-1i*pi*(N-1)/N.*n);
                                        for i = 1:t_num
                                            Hk(n_c2+i) = Hk(n_c2+i) * t_coff(i);
                                            Hk(n_c1-i) = Hk(n_c1-i) * t_coff(i);
                                        end
                                        Hk = [Hk, flip(conj(Hk(2:ceil(N/2))))];
                                    end
                                case 'bandstop'
                                    wc1 = (wp(1)+ws(1))/2;
                                    wc2 = (wp(2)+ws(2))/2;
                                    n_c1 = floor(wc1/(2*pi)*N);
                                    n_c2 = ceil(wc2/(2*pi)*N);
                                    if mod(N, 2) == 0
                                        Hk = [ones([1,n_c1+1]),zeros([1,n_c2-n_c1-2]),ones([1,N/2+1-n_c2+1])];
                                        n = 1:(N/2+1);
                                    else
                                        Hk = [ones([1,n_c1+1]),zeros([1,n_c2-n_c1-2]),ones([1,ceil(N/2)-n_c2+1])];
                                        n = 1:ceil(N/2);
                                    end  
                                    temp = exp(-1i*pi*(N-1)/N.*n);
                                    Hk = Hk .* temp;
                                    for i = 1:t_num
                                        Hk(n_c1+1+i) = Hk(n_c1+1+i) * t_coff(i);
                                        Hk(n_c2+1-i) = Hk(n_c2+1-i) * t_coff(i);
                                    end
                                    if mod(N, 2) == 0
                                        Hk = [Hk, flip(conj(Hk(2:N/2)))];
                                    else
                                        Hk = [Hk, flip(conj(Hk(2:ceil(N/2))))];
                                    end
                            end
                            w = 0:1/N:1-1/N;
                            plot(app.amp, w, abs(Hk));
                            plot(app.angle, w, angle(Hk));
                            title(app.amp, 'FIR frequency sample Amp response');
                            title(app.angle, 'FIR frequency sample angle response');
                            xlabel(app.amp, 'w/pi'); xlabel(app.angle, 'w/pi');
                            ylabel(app.amp, 'Amp'); ylabel(app.angle, 'Angle');
                        otherwise
                            N = app.FIRlengthEditField.Value;
                            n = 0:1:N-1;
                            tao = (N-1)/2;
                            h_d=zeros([1,N]);
                            wp = str2num(app.wp.Value)*pi;
                            switch app.Filtercategory.Value
                                case 'lowpass'
                                   for i = 1:N
                                        if i-1==tao
                                            h_d(i)=wp/pi;
                                            continue;
                                        end
                                        h_d(i)=sin((i-1-tao)*wp)./(pi*(i-1-tao));
                                    end 
                                case 'highpass'
                                    for i = 1:N
                                        if i-1==tao
                                            h_d(i)=1-wp/pi;
                                            continue;
                                        end
                                        h_d(i)=-(sin((i-1-tao)*wp)./(pi*(i-1-tao)));
                                    end
                                case 'bandpass'
                                    for i = 1:N
                                        if i-1==tao
                                            h_d(i)=(wp(2)-wp(1))/pi;
                                            continue;
                                        end
                                        h_d(i)=1/(pi*(i-1-tao))*(sin(wp(2)*(i-tao-1))-sin(wp(1)*(i-1-tao)));
                                    end
                                case 'bandstop'
                                    for i = 1:N
                                        if i-1==tao
                                            h_d(i)=1-wp(2)/pi+wp(1)/pi;
                                            continue;
                                        end
                                        h_d(i)=1/(pi*(i-1-tao))*(sin(pi*(i-tao-1))+sin(wp(1)*(i-tao-1))-sin(wp(2)*(i-tao-1)));
                                    end
                            end
                            switch app.windowcategory.Value
                                case 'triang'
                                    W = triang(N).';
                                case 'boxcar'
                                    W = boxcar(N).';
                                case 'Hanning'
                                    W = hanning(N).';
                                case 'Hamming'
                                    W = hamming(N).';
                                case 'blackman'
                                    W = blackman(N).';
                                case 'Kaiser'
                                    beta = app.KaiserbetaEditField.Value;
                                    W = kaiser(N, beta);
                            end
                            r = h_d .* W;
                            f = fft(r, N);
                            w = (0:1:N-1)/N;
                            plot(app.amp, w, abs(f), 'b');
                            plot(app.angle, w, angle(f), 'r');
                            xlabel(app.amp, 'w/pi'); xlabel(app.angle, 'w/pi');
                            ylabel(app.amp, 'Amp'); ylabel(app.angle, 'Angle');
                            title(app.amp, 'FIR window function Amp response');
                            title(app.angle, 'FIR window function angle response');
                    end
            end
        end

        % Value changed function: Filterlengthcategory
        function FilterlengthcategoryValueChanged(app, event)
            value = app.Filterlengthcategory.Value;
            switch value
                case 'IIR'
                    app.IIRcategory.Visible = 'on';
                    app.FiltercategoryDropDown_3Label_2.Visible = 'on';
                    app.FIRdesigntype.Visible = 'off';
                    app.FIRlengthEditField.Visible = 'off';
                    app.windowcategory.Visible = 'off';
                    app.KaiserbetaEditField.Visible = 'off';
                    app.TcoefficientEditField.Visible = 'off';
                    app.FIRlengthEditFieldLabel.Visible = 'off';
                    app.FiltercategoryDropDown_3Label_3.Visible = 'off';
                    app.FiltercategoryDropDown_3Label_4.Visible = 'off';
                    app.KaiserbetaEditFieldLabel.Visible = 'off';
                    app.TcoefficientLabel.Visible = 'off';
                    app.ws.Visible = 'on';
                    app.Label_2.Visible = 'on';
                otherwise
                    app.IIRcategory.Visible = 'off';
                    app.FiltercategoryDropDown_3Label_2.Visible = 'off';
                    app.FIRdesigntype.Visible = 'on';
                    app.FIRlengthEditField.Visible = 'on';
                    app.windowcategory.Visible = 'off';
                    app.KaiserbetaEditField.Visible = 'off';
                    app.TcoefficientEditField.Visible = 'off';
                    app.FIRlengthEditFieldLabel.Visible = 'on';
                    app.FiltercategoryDropDown_3Label_3.Visible = 'on';
                    app.FiltercategoryDropDown_3Label_4.Visible = 'off';
                    app.KaiserbetaEditFieldLabel.Visible = 'off';
                    app.TcoefficientLabel.Visible = 'off';
            end
        end

        % Value changed function: FIRdesigntype
        function FIRdesigntypeValueChanged(app, event)
            value = app.FIRdesigntype.Value;
            switch value
                case "window function"
                    app.windowcategory.Visible = 'on';
                    app.FiltercategoryDropDown_3Label_4.Visible = 'on';
                    app.TcoefficientEditField.Visible = 'off';
                    app.TcoefficientLabel.Visible = 'off';
                    app.KaiserbetaEditField.Visible = 'off';
                    app.KaiserbetaEditFieldLabel.Visible = 'off';
                    app.ws.Visible = 'off';
                    app.Label_2.Visible = 'off';
                otherwise
                    app.windowcategory.Visible = 'off';
                    app.FiltercategoryDropDown_3Label_4.Visible = 'off';
                    app.TcoefficientEditField.Visible = 'on';
                    app.TcoefficientLabel.Visible = 'on';
                    app.KaiserbetaEditField.Visible = 'off';
                    app.KaiserbetaEditFieldLabel.Visible = 'off';
                    app.ws.Visible = 'on';
                    app.Label_2.Visible = 'on';
            end
        end

        % Value changed function: windowcategory
        function windowcategoryValueChanged(app, event)
            value = app.windowcategory.Value;
            switch value
                case 'Kaiser'
                    app.KaiserbetaEditField.Visible = 'on';
                    app.KaiserbetaEditFieldLabel.Visible = 'on';
                otherwise
                    app.KaiserbetaEditField.Visible = 'off';
                    app.KaiserbetaEditFieldLabel.Visible = 'off';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FilterlengthcategoryDropDownLabel = uilabel(app.LeftPanel);
            app.FilterlengthcategoryDropDownLabel.Text = 'Filter length category';
            app.Filterlengthcategory = uidropdown(app.LeftPanel);
            app.Filterlengthcategory.Items = {'None', 'IIR', 'FIR'};
            app.Filterlengthcategory.ValueChangedFcn = createCallbackFcn(app, @FilterlengthcategoryValueChanged, true);
            app.Filterlengthcategory.Value = 'None';
            app.FiltercategoryDropDown_3Label = uilabel(app.LeftPanel);
            app.FiltercategoryDropDown_3Label.Text = 'Filter category';
            app.Filtercategory = uidropdown(app.LeftPanel);
            app.Filtercategory.Items = {'lowpass', 'highpass', 'bandpass', 'bandstop'};
            app.Filtercategory.Value = 'lowpass';
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @Update_cb, true);
            app.UpdateButton.Text = 'Update';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'wp(归一化pi)';
            app.wp = uieditfield(app.LeftPanel, 'text');
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'ws(归一化pi)';
            app.ws = uieditfield(app.LeftPanel, 'text');
            app.FiltercategoryDropDown_3Label_2 = uilabel(app.LeftPanel);
            app.FiltercategoryDropDown_3Label_2.Visible = 'off';
            app.FiltercategoryDropDown_3Label_2.Text = 'IIR category';
            app.IIRcategory = uidropdown(app.LeftPanel);
            app.IIRcategory.Items = {'Butterworth', 'Chebyshey I'};
            app.IIRcategory.Visible = 'off';
            app.IIRcategory.Value = 'Butterworth';
            app.FIRlengthEditFieldLabel = uilabel(app.LeftPanel);
            app.FIRlengthEditFieldLabel.Visible = 'off';
            app.FIRlengthEditFieldLabel.Text = 'FIR length';
            app.FIRlengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FIRlengthEditField.Visible = 'off';
            app.FiltercategoryDropDown_3Label_3 = uilabel(app.LeftPanel);
            app.FiltercategoryDropDown_3Label_3.Visible = 'off';
            app.FiltercategoryDropDown_3Label_3.Text = 'FIR design type ';
            app.FIRdesigntype = uidropdown(app.LeftPanel);
            app.FIRdesigntype.Items = {'None', 'window function', 'frequency sample'};
            app.FIRdesigntype.ValueChangedFcn = createCallbackFcn(app, @FIRdesigntypeValueChanged, true);
            app.FIRdesigntype.Visible = 'off';
            app.FIRdesigntype.Value = 'None';
            app.FiltercategoryDropDown_3Label_4 = uilabel(app.LeftPanel);
            app.FiltercategoryDropDown_3Label_4.Visible = 'off';
            app.FiltercategoryDropDown_3Label_4.Text = 'Window category';
            app.windowcategory = uidropdown(app.LeftPanel);
            app.windowcategory.Items = {'triang', 'boxcar', 'Hamming', 'Hanning', 'blackman', 'Kaiser'};
            app.windowcategory.ValueChangedFcn = createCallbackFcn(app, @windowcategoryValueChanged, true);
            app.windowcategory.Visible = 'off';
            app.windowcategory.Value = 'triang';
            app.KaiserbetaEditFieldLabel = uilabel(app.LeftPanel);
            app.KaiserbetaEditFieldLabel.Visible = 'off';
            app.KaiserbetaEditFieldLabel.Text = 'Kaiser beta';
            app.KaiserbetaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.KaiserbetaEditField.Visible = 'off';
            app.TcoefficientLabel = uilabel(app.LeftPanel);
            app.TcoefficientLabel.Visible = 'off';
            app.TcoefficientLabel.Text = 'T coefficient(大在前)';
            app.TcoefficientEditField = uieditfield(app.LeftPanel, 'text');
            app.TcoefficientEditField.Visible = 'off';
            app.AsEditFieldLabel = uilabel(app.LeftPanel);
            app.AsEditFieldLabel.Text = 'As';
            app.AsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RpEditFieldLabel = uilabel(app.LeftPanel);
            app.RpEditFieldLabel.Text = 'Rp';
            app.RpEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.amp = uiaxes(app.RightPanel);
            title(app.amp, 'Title')
            xlabel(app.amp, 'X')
            ylabel(app.amp, 'Y')
            zlabel(app.amp, 'Z')
            app.angle = uiaxes(app.RightPanel);
            title(app.angle, 'Title')
            xlabel(app.angle, 'X')
            ylabel(app.angle, 'Y')
            zlabel(app.angle, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end